classdef RAVEN_desk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image2                          matlab.ui.control.Image
        SIGNALNORMALISATIONPanel        matlab.ui.container.Panel
        SignalNormalisation             matlab.ui.container.GridLayout
        MethodDropDown                  matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        MethodLabel                     matlab.ui.control.Label
        ConfirmationLabel               matlab.ui.control.Label
        TargetScaleValue                matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TargetScaleLabel                matlab.ui.control.Label
        EnableDisableLabel              matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        RUNRAVENButton                  matlab.ui.control.Button
        SELECTTHESAVEPATHPanel          matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        SavePathEditField               matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        THRESHOLDSButton                matlab.ui.control.Button
        GUIDEButton                     matlab.ui.control.Button
        SELECTMICROSTRUCTURESFORANALYSISPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DeltaWaveCheckBox               matlab.ui.control.CheckBox
        CAPCheckBox                     matlab.ui.control.CheckBox
        KComplexCheckBox                matlab.ui.control.CheckBox
        SpindleCheckBox                 matlab.ui.control.CheckBox
        DEFINECHANNELSOFINTERESTPanel   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ResetSelectionButton            matlab.ui.control.Button
        SelectedSignalsListBox          matlab.ui.control.ListBox
        SelectedSignalsListBoxLabel     matlab.ui.control.Label
        CAPAnalysisSignalsListBox       matlab.ui.control.ListBox
        CAPAnalysisSignalsListBoxLabel  matlab.ui.control.Label
        ReferenceDropDown               matlab.ui.control.DropDown
        ReferenceDropDownLabel          matlab.ui.control.Label
        AvailableSignalsListBox         matlab.ui.control.ListBox
        AvailableSignalsListBoxLabel    matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        AddCAPButton                    matlab.ui.control.Button
        ShowSignalsButton               matlab.ui.control.Button
        DEFINEFILEFORMATPATHANDMODEPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SelectAnalysisModeDropDown      matlab.ui.control.DropDown
        SelectAnalysisModeLabel         matlab.ui.control.Label
        DirectoryPathForFilesEditField  matlab.ui.control.EditField
        DirectoryPathForFilesEditFieldLabel  matlab.ui.control.Label
        ChooseTheFileFormatDropDown     matlab.ui.control.DropDown
        ChooseTheFileFormatDropDownLabel  matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        DEFINESAMPLINGFREQUENCIESFORANALYSISPanel  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        SamplingFrequencyCAPLabel       matlab.ui.control.Label
        SamplingFrequencySpindleKcomplexDeltawavesLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        FsMicro
        FsCap
        FileType
        AnalysisMode
        SelectedDirectory
        FileName
        ChannelsToLoad
        SignalNamesMicro
        SignalNamesCap
        SelectedMicroStructures
        FilePath
        SavePath
        TargetScale
        NormalisationEnabled = false
        NormMethod
    end
    
    properties (Access = public)
        Thresholds struct
        DefaultThresholds struct
    end
    
    methods (Access = private)
        
        function updateSamplingFrequencyEditability(app)

            anySelected = app.SpindleCheckBox.Value || ...
                          app.KComplexCheckBox.Value || ...
                          app.DeltaWaveCheckBox.Value;
        
            if anySelected
                app.EditField.Editable = "on";
            else
                app.EditField.Editable = "off";
            end
        end
        
        function hideLabel(app)
            app.ConfirmationLabel.Visible = "off";          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.UIFigure.Icon = 'RAVEN_logo.png';
            app.UIFigure.Name = 'RAVEN';

            app.FileType = app.ChooseTheFileFormatDropDown.Value;
            app.FsMicro = app.EditField.Value;
            app.FsCap = app.EditField_2.Value;
            app.AnalysisMode = app.SelectAnalysisModeDropDown.Value;
            
            app.Thresholds = struct( ...
                    'Correlation_Kcomplex', 0.8, ...
                    'Envelope_Kcomplex',    1.8, ...
                    'RMS_Kcomplex',         17, ...
                    'Power_Kcomplex',       2.8, ...
                    'CWT_Kcomplex',         16, ...
                    'P2P_spindle',          26, ...
                    'RelativePower_spindle',0.9, ...
                    'Envelope_spindle',     2, ...
                    'RMS_spindle',          8, ...
                    'Power_spindle',        1.8, ...
                    'CWT_spindle',          12, ...
                    'WaveLength_SWS',       0.35, ...
                    'Envelope_SWS',         2.5, ...
                    'Power_SWS',            2.4, ...
                    'RMS_SWS',              33, ...
                    'CWT_SWS',              15, ...
                    'Desc_CAP',             0.16, ...
                    'Envelope_CAP',         3, ...
                    'Power_CAP',            2, ...
                    'AmpDiff_CAP',          50, ...
                    'CWT_CAP',              10 ...
                );

            app.DefaultThresholds = struct( ...
                    'Correlation_Kcomplex', 0.8, ...
                    'Envelope_Kcomplex',    1.8, ...
                    'RMS_Kcomplex',         17, ...
                    'Power_Kcomplex',       2.8, ...
                    'CWT_Kcomplex',         16, ...
                    'P2P_spindle',          26, ...
                    'RelativePower_spindle',0.9, ...
                    'Envelope_spindle',     2, ...
                    'RMS_spindle',          8, ...
                    'Power_spindle',        1.8, ...
                    'CWT_spindle',          12, ...
                    'WaveLength_SWS',       0.35, ...
                    'Envelope_SWS',         2.5, ...
                    'Power_SWS',            2.4, ...
                    'RMS_SWS',              33, ...
                    'CWT_SWS',              15, ...
                    'Desc_CAP',             0.16, ...
                    'Envelope_CAP',         3, ...
                    'Power_CAP',            2, ...
                    'AmpDiff_CAP',          50, ...
                    'CWT_CAP',              10 ...
                );
            
        end

        % Value changed function: SelectAnalysisModeDropDown
        function IndividualFocus(app, event)
            value = app.SelectAnalysisModeDropDown.Value;
            if strcmp(value, 'Individual Focus')
                app.FilenameEditField.Visible = "on";
                app.FilenameEditFieldLabel.Visible = "on";
                app.FilenameEditField.Placeholder = '.edf / ID number (SLF)';
                app.AnalysisMode = app.SelectAnalysisModeDropDown.Value;
            else
                app.FilenameEditField.Visible = "off";
                app.FilenameEditFieldLabel.Visible = "off";
                app.AnalysisMode = app.SelectAnalysisModeDropDown.Value;
            end

            if ~isempty(app.SelectedSignalsListBox.Items)
                app.SelectedSignalsListBox.Items = {};
            end
            if ~isempty(app.CAPAnalysisSignalsListBox.Items)
                app.CAPAnalysisSignalsListBox.Items = {};
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Open directory selection dialog
            selectedDir = uigetdir(pwd, 'Select Directory');
        
            % User pressed Cancel
            if selectedDir == 0
                return;
            end
        
            % Set edit field text to selected directory
            app.SelectedDirectory = selectedDir;
            app.DirectoryPathForFilesEditField.Value = selectedDir;
            if ~isempty(app.SelectedSignalsListBox.Items)
                app.SelectedSignalsListBox.Items = {};
            end
            if ~isempty(app.CAPAnalysisSignalsListBox.Items)
                app.CAPAnalysisSignalsListBox.Items = {};
            end
        end

        % Button pushed function: ShowSignalsButton
        function ShowSignalsButtonPushed(app, event)
            % Validate directory
            if isempty(app.SelectedDirectory) || ~isfolder(app.SelectedDirectory)
                uialert(app.UIFigure, ...
                    'Please select a valid directory first.', ...
                    'No Directory Selected');
                return;
            end

            fileType = app.FileType;
            fileType = lower(fileType);

            % Visual feedback
            app.UIFigure.Pointer = 'watch';
            drawnow;

            allLabels = {};
            
            try
                switch fileType
        
                    % ==========================================================
                    % EDF FILES
                    % ==========================================================
                    case 'edf'
                        files = dir(fullfile(app.SelectedDirectory, '*.edf'));
        
                        if isempty(files)
                            error('No EDF files found in selected directory.');
                        end
        
                        for k = 1:numel(files)
                            filePath = fullfile(files(k).folder, files(k).name);
        
                            [~, ~, ~, ch_info] = edf_fileread( ...
                                filePath, ...
                                'event', false, ...
                                {} );
        
                            labels = ch_info.label(:);
        
                            % Remove invalid labels
                            labels(strcmpi(labels, 'none')) = [];
        
                            allLabels = [allLabels; labels];
                        end
        
        
                    % ==========================================================
                    % SLF FILES (folder-based)
                    % ==========================================================
                    case 'slf'

                        % Get subject folders
                        subjects = dir(app.SelectedDirectory);
                        subjects = subjects([subjects.isdir]);
                        subjects = subjects(~ismember({subjects.name}, {'.', '..'}));
                    
                        if isempty(subjects)
                            error('No SLF folders found in selected directory.');
                        end
                    
                        allLabels = {};
                    
                        % Loop through subjects
                        for s = 1:numel(subjects)
                            subjectPath = fullfile(subjects(s).folder, subjects(s).name);
                    
                            % Get signal folders inside each subject
                            signals = dir(subjectPath);
                            signals = signals([signals.isdir]);
                            signals = signals(~ismember({signals.name}, {'.', '..'}));
                    
                            if isempty(signals)
                                continue; % subject without signals
                            end
                    
                            signalNames = {signals.name}';
                            allLabels = [allLabels; signalNames]; 
                        end
                        
                    otherwise
                        error('Unsupported file type.');
        
                end
        
            catch ME
                app.UIFigure.Pointer = 'arrow';
                uialert(app.UIFigure, ME.message, 'Signal Load Error');
                return;
            end
        
            % Remove duplicates (case-insensitive, stable order)
            [~, idx] = unique(lower(allLabels), 'stable');
            uniqueLabels = allLabels(idx);
        
            % Populate list box
            app.AvailableSignalsListBox.Items = uniqueLabels;
            app.AvailableSignalsListBox.Value = {};
            app.ReferenceDropDown.Items = [{'None'}; uniqueLabels];
        
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: ChooseTheFileFormatDropDown
        function FormatChanged(app, event)
            value = app.ChooseTheFileFormatDropDown.Value;
            if strcmp(value, 'EDF')
                app.FileType = app.ChooseTheFileFormatDropDown.Value;
            elseif strcmp(value, 'SLF')
                app.FileType = app.ChooseTheFileFormatDropDown.Value;
            end
            if ~isempty(app.SelectedSignalsListBox.Items)
                app.SelectedSignalsListBox.Items = {};
            end
            if ~isempty(app.CAPAnalysisSignalsListBox.Items)
                app.CAPAnalysisSignalsListBox.Items = {};
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            selected = listboxToCell(app.AvailableSignalsListBox.Value);
            if isempty(selected)
                return;
            end

            % Current items
            leftItems  = listboxToCell(app.AvailableSignalsListBox.Items);
            rightItems = listboxToCell(app.SelectedSignalsListBox.Items);

            % Force column cell arrays
            leftItems  = leftItems(:);
            rightItems = rightItems(:);
            selected   = selected(:);

            ref = app.ReferenceDropDown.Value;

            if ~strcmpi(ref, 'None')
                % Build re-referenced signal labels: e.g. C4-M1
                selected = strcat(selected, '-', ref);
            end

            % Check if selected configurations already exist
            alreadySelected = ismember(selected, rightItems);
            
            if all(alreadySelected)
                uialert(app.UIFigure, ...
                    "The selected signal configuration has already been added.", ...
                    "Duplicate Signal");
                return;
            end
        
            % Append selected to rightItems safely
            rightItems = [rightItems; selected];
            rightItems = unique(rightItems, 'stable');   % remove duplicates
        

            % Update list boxes
            app.AvailableSignalsListBox.Items = leftItems;
            app.SelectedSignalsListBox.Items = rightItems;

            % Reset selection
            app.AvailableSignalsListBox.Value = {};
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % ============================
            % Remove from SelectedSignalsListBox
            % ============================
            selectedMain = listboxToCell(app.SelectedSignalsListBox.Value);
            if ~isempty(selectedMain)
        
                rightItems = listboxToCell(app.SelectedSignalsListBox.Items);
        
                rightItems = setdiff(rightItems, selectedMain, 'stable');
        
                app.SelectedSignalsListBox.Items = rightItems;
                app.SelectedSignalsListBox.Value = {};
            end
        
        
            % ============================
            % Remove from CAPAnalysisSignalsListBox
            % ============================
            selectedCAP = listboxToCell(app.CAPAnalysisSignalsListBox.Value);
            if ~isempty(selectedCAP)
        
                capItems = listboxToCell(app.CAPAnalysisSignalsListBox.Items);
        
                capItems = setdiff(capItems, selectedCAP, 'stable');
        
                app.CAPAnalysisSignalsListBox.Items = capItems;
                app.CAPAnalysisSignalsListBox.Value = {};
            end

        end

        % Button pushed function: AddCAPButton
        function AddCAPButtonPushed(app, event)
            selected = listboxToCell(app.AvailableSignalsListBox.Value);
            if isempty(selected)
                return;
            end

            % Current items
            leftItems  = listboxToCell(app.AvailableSignalsListBox.Items);
            rightItems = listboxToCell(app.CAPAnalysisSignalsListBox.Items);

            if numel(rightItems) >= 2 || numel(selected) > 2
                uialert(app.UIFigure, ...
                    'Maximum of two signals can be selected for CAP analysis.', ...
                    'Selection Limit Exceeded', ...
                    'Icon','warning');
                return
            end

            % Force column cell arrays
            leftItems  = leftItems(:);
            rightItems = rightItems(:);
            selected   = selected(:);

            ref = app.ReferenceDropDown.Value;

            if ~strcmpi(ref, 'None')
                % Build re-referenced signal labels: e.g. C4-M1
                selected = strcat(selected, '-', ref);
            end

            % Check if selected configurations already exist
            alreadySelected = ismember(selected, rightItems);
            
            if all(alreadySelected)
                uialert(app.UIFigure, ...
                    "The selected signal configuration has already been added.", ...
                    "Duplicate Signal");
                return;
            end
        
            % Append selected to rightItems safely
            rightItems = [rightItems; selected];
            rightItems = unique(rightItems, 'stable');   % remove duplicates
        

            % Update list boxes
            app.AvailableSignalsListBox.Items = leftItems;
            app.CAPAnalysisSignalsListBox.Items = rightItems;

            % Reset selection
            app.AvailableSignalsListBox.Value = {};
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            % Open directory selection dialog
            selectedDir = uigetdir(pwd, 'Select Directory');
        
            % User pressed Cancel
            if selectedDir == 0
                return;
            end
        
            % Set edit field text to selected directory
            app.SavePath = selectedDir;
            app.SavePathEditField.Value = selectedDir;
        end

        % Button pushed function: THRESHOLDSButton
        function ThresholdsButtonPushed(app, event)
            ThresholdsApp(app.Thresholds, app.DefaultThresholds, app)
        end

        % Button pushed function: RUNRAVENButton
        function RunRavenButonPushed(app, event)

            % ---------------------------------------------------------
            % 1. Check the sampling frequencies
            % ---------------------------------------------------------
            fsMicro = app.FsMicro;
            % Check: exists, numeric, finite, integer, positive
            if isempty(fsMicro) || ...
               ~isnumeric(fsMicro) || ...
               ~isscalar(fsMicro) || ...
               ~isfinite(fsMicro) || ...
               fsMicro <= 0 || ...
               fsMicro == 0 || ...
               fsMicro ~= round(fsMicro)
        
                uialert(app.UIFigure, ...
                    ['Invalid sampling frequency.' newline newline ...
                     'Sampling frequency must be a positive integer.'], ...
                    'Invalid sampling frequency');
                drawnow;
                return;
            end

            fsCap = app.FsCap;
            % Check: exists, numeric, finite, integer, positive
            if isempty(fsCap) || ...
               ~isnumeric(fsCap) || ...
               ~isscalar(fsCap) || ...
               ~isfinite(fsCap) || ...
               fsCap <= 0 || ...
               fsCap == 0 || ...
               fsCap ~= round(fsCap)
        
                uialert(app.UIFigure, ...
                    ['Invalid sampling frequency.' newline newline ...
                     'Sampling frequency must be a positive integer.'], ...
                    'Invalid sampling frequency');
                drawnow;
                return;
            end


            % ---------------------------------------------------------
            % 2. Check the selected microstructures
            % ---------------------------------------------------------
            annotationMap = struct( ...
                'spindles',    app.SpindleCheckBox.Value, ...
                'kcomplexes',  app.KComplexCheckBox.Value, ...
                'cap',         app.CAPCheckBox.Value, ...
                'delta_waves', app.DeltaWaveCheckBox.Value );
        
            selectedTypes = fieldnames(annotationMap);
            selectedTypes = selectedTypes(struct2array(annotationMap));
        
            if isempty(selectedTypes)
                uialert(app.UIFigure, ...
                    'No microstructures selected.', ...
                    'Nothing to Load');
                drawnow;
                return;
            end


            % ---------------------------------------------------------
            % 2. Configuration consistency check: K-complexes vs delta waves
            % ---------------------------------------------------------
            if app.KComplexCheckBox.Value && ~app.DeltaWaveCheckBox.Value
            
                selection = uiconfirm( ...
                    app.UIFigure, ...
                    ['K-complex detection is adviced to be performed together with delta wave detection. ' ...
                     'Running the analysis without delta wave detection is not recommended and may result ' ...
                     'in increased sensitivity and reduced specificity of K-complex detection.' newline newline ...
                     'You may continue with K-complex analysis only, or revise the selected analysis options.'], ...
                    'Analysis configuration warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'Icon', 'warning');
            
                if strcmp(selection, 'Cancel')
                    return;
                end
            end


            % ---------------------------------------------------------
            % 2. Check the folder path and filename
            % ---------------------------------------------------------
            app.FilePath = app.DirectoryPathForFilesEditField.Value;
            app.FileName = app.FilenameEditField.Value;

            if isempty(app.FilePath)
                uialert(app.UIFigure, ...
                    'Missing file/folder path.', ...
                    'Missing file path');
                drawnow;
                return;
            end

            if strcmp(app.SelectAnalysisModeDropDown.Value, 'Individual Focus') && isempty(app.FilenameEditField.Value)
                uialert(app.UIFigure, ...
                    'Missing filename for subject.', ...
                    'Missing subject name');
                drawnow;
                return;
            end

            if strcmp(app.SelectAnalysisModeDropDown.Value, 'Individual Focus') && ~isempty(app.FilenameEditField.Value)
                fullPath = fullfile(app.FilePath, app.FileName);
                % Accept either a file or a folder
                if ~(isfile(fullPath) || isfolder(fullPath))
                    uialert(app.UIFigure, ...
                        'Selected filename not found in given directory.', ...
                        'Mismatch filename and path');
                    drawnow;
                    return;
                end
            end


            % ---------------------------------------------------------
            % 3. Check the selected signals 
            % ---------------------------------------------------------
            selectedSignals = listboxToCell(app.SelectedSignalsListBox.Items);
            selectedSignalsCap = listboxToCell(app.CAPAnalysisSignalsListBox.Items);
            requiredTypes = {'spindles','delta_waves','kcomplexes'};
            requiredTypesCap = {'cap'};

            % Check if CAP analysis is enabled
            capEnabled = any(strcmp(selectedTypes, 'cap'));
            
            if capEnabled

                nCap = numel(selectedSignalsCap);
            
                % --------------------------------------------------------
                % Case 1: Two signals selected but they are identical
                % --------------------------------------------------------
                if nCap == 2
                    sigs = lower(string(selectedSignalsCap));
            
                    if numel(unique(sigs)) < 2
                        uialert(app.UIFigure, ...
                            ['CAP analysis requires two different signals.' newline ...
                             'The same signal was selected twice.'], ...
                            'Invalid CAP Signals');
                        drawnow;
                        return;
                    end
                end
            
                % --------------------------------------------------------
                % Case 2: Only one signal selected (soft warning)
                % --------------------------------------------------------
                if nCap == 1
                    msg = [
                        "Only one signal is selected for CAP analysis." newline ...
                        "CAP detection is designed to work with two different signals." newline ...
                        "Running with a single signal is possible but may reduce accuracy." newline ...
                        "Do you want to continue?"
                    ];
            
                    choice = uiconfirm(app.UIFigure, ...
                        msg, ...
                        'Single CAP Signal', ...
                        'Options', {'Continue', 'Cancel'}, ...
                        'DefaultOption', 2, ...
                        'CancelOption', 2);
            
                    if strcmp(choice, 'Cancel')
                        drawnow;
                        return;
                    end
                end
            
            end

            % Check if any required type is selected
            requiresSignals = any(ismember(selectedTypes, requiredTypes));
            requiresSignalsCap = any(ismember(selectedTypes, requiredTypesCap));
            
            if requiresSignals && isempty(selectedSignals)
                uialert(app.UIFigure, ...
                    'Spindles / Delta Waves / K-Complexes enabled but no signals selected.', ...
                    'Missing signals');
                drawnow;
                return;
            end
            
            if ~requiresSignals && ~isempty(selectedSignals)
                uialert(app.UIFigure, ...
                    'Signals selected but Spindles / Delta Waves / K-Complexes are not enabled.', ...
                    'Signal selection mismatch');
                drawnow;
                return;
            end


            if ~requiresSignalsCap && ~isempty(selectedSignalsCap)
                uialert(app.UIFigure, ...
                    ['CAP signals are selected, but CAP analysis is not enabled.' newline ...
                     'Please enable CAP or remove CAP signals.'], ...
                    'CAP selection mismatch');
                drawnow;
                return;
            end

            if isempty(selectedSignalsCap) && any(strcmp(selectedTypes, 'cap'))
                uialert(app.UIFigure, ...
                    'CAP is enabled but no CAP signals are selected.', ...
                    'Missing CAP signals');
                drawnow;
                return;
            end

            % Combine all selected signals (micro + CAP)
            allSelectedSignals = [
                selectedSignals(:)
                selectedSignalsCap(:)
            ];


            channelsToLoad = {};  % store unique channel names

            for s = 1:numel(allSelectedSignals)
                sigName = allSelectedSignals{s};
                
                if contains(sigName, '-')
                    parts = split(sigName, '-');
                    chan1 = parts{1};
                    chan2 = parts{2};
                    channelsToLoad = [channelsToLoad, chan1, chan2];
                else
                    channelsToLoad = [channelsToLoad, sigName];
                end
            end
            
            channelsToLoad = unique(channelsToLoad);  % only load each channel once
            channelsToLoad = channelsToLoad(:);

            % ---------------------------------------------------------
            % 4. Check if any of the subjects doesnt have these selected
            % signals
            % ---------------------------------------------------------
            missingReport = strings(0);  % collect subjects with missing channels

            switch lower(app.FileType)
            
                % ==========================================================
                % EDF FILES (each file = one subject)
                % ==========================================================
                case 'edf'
            
                    files = dir(fullfile(app.SelectedDirectory, '*.edf'));
            
                    if isempty(files)
                        uialert(app.UIFigure, ...
                            'No EDF files found in the selected directory.', ...
                            'Missing EDF files');
                        drawnow;
                        return;
                    end
            
                    for k = 1:numel(files)
                        filePath = fullfile(files(k).folder, files(k).name);
            
                        try
                            [~, ~, ~, ch_info] = edf_fileread(filePath, 'event', false, {});
                        catch
                            missingReport(end+1) = files(k).name + ": unreadable EDF file";
                            continue;
                        end
            
                        available = lower(string(ch_info.label(:)));
                        available(available == "none") = [];
            
                        missing = setdiff(lower(channelsToLoad), available);
            
                        if ~isempty(missing)
                            missingReport(end+1) = ...
                                files(k).name + ": missing " + strjoin(upper(missing), ", ");
                        end
                    end
            
            
                % ==========================================================
                % SLF FILES (each folder = one subject)
                % ==========================================================
                case 'slf'
            
                    subjects = dir(app.SelectedDirectory);
                    subjects = subjects([subjects.isdir]);
                    subjects = subjects(~ismember({subjects.name}, {'.','..'}));
            
                    if isempty(subjects)
                        uialert(app.UIFigure, ...
                            'No SLF subject folders found in the selected directory.', ...
                            'Missing SLF folders');
                        drawnow;
                        return;
                    end
            
                    for s = 1:numel(subjects)
                        subjectName = subjects(s).name;
                        subjectPath = fullfile(subjects(s).folder, subjectName);
            
                        signals = dir(subjectPath);
                        signals = signals([signals.isdir]);
                        signals = signals(~ismember({signals.name}, {'.','..'}));
            
                        available = lower(string({signals.name}));
            
                        missing = setdiff(lower(channelsToLoad), available);
            
                        if ~isempty(missing)
                            missingReport(end+1) = ...
                                subjectName + ": missing " + strjoin(upper(missing), ", ");
                        end
                    end
            
                otherwise
                    uialert(app.UIFigure, ...
                        'Unsupported file type.', ...
                        'Configuration Error');
                    drawnow;
                    return;
            end
            
            % ------------------------------------------------------------
            % Report missing channels (if any)
            % ------------------------------------------------------------
            if ~isempty(missingReport)

                msg = [
                    "Some subjects do not contain all required signals and will be skipped during analysis." newline newline ...
                    "Affected subjects:" newline ...
                    strjoin(missingReport, newline) newline newline ...
                    "You may:" newline ...
                    "• Continue and analyze only valid subjects" newline ...
                    "• Cancel and modify signal selection or run a different analysis" newline newline ...
                    "Are you sure you want to continue?"
                ];
            
                selection = uiconfirm(app.UIFigure, ...
                    msg, ...
                    'Missing Channels', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2);
            
                if strcmp(selection, 'Cancel')
                    drawnow;
                    return;
                end
            
                % If Continue → proceed, invalid subjects will be skipped
            end

            % ------------------------------------------------------------
            % Save the selected signals
            % ------------------------------------------------------------


            % ---------------------------------------------------------
            % 5. Check the save path
            % ---------------------------------------------------------
            if isempty(app.SavePathEditField.Value)
                uialert(app.UIFigure, ...
                    'Missing save path for analysis.', ...
                    'Missing savepath');
                drawnow;
                return;
            end

            % ------------------------------------------------------------
            % Commit configuration 
            % ------------------------------------------------------------
            app.SignalNamesMicro        = selectedSignals;
            app.SignalNamesCap          = selectedSignalsCap;
            app.ChannelsToLoad          = channelsToLoad;
            app.SelectedMicroStructures = selectedTypes;

            % ---------------------------------------------------------
            % 6. Run RAVEN
            % ---------------------------------------------------------

                RAVEN_desktop(app,...
                    app.FsMicro,...
                    app.FsCap,...
                    app.FilePath,...
                    app.FileName,...
                    app.ChannelsToLoad,...
                    app.SignalNamesMicro,...
                    app.SignalNamesCap,...
                    app.SelectedMicroStructures,...
                    app.SavePath,...
                    app.AnalysisMode,...
                    app.FileType,...
                    app.Thresholds,...
                    app.TargetScale,...
                    app.NormalisationEnabled,...
                    app.NormMethod);
            

        end

        % Value changed function: EditField
        function FsMicroChanged(app, event)
            value = app.EditField.Value;
            app.FsMicro = value;
        end

        % Value changed function: EditField_2
        function FsCapChanged(app, event)
            value = app.EditField_2.Value;
            app.FsCap = value;
        end

        % Value changed function: SpindleCheckBox
        function SpindleSelected(app, event)
            app.updateSamplingFrequencyEditability();
        end

        % Value changed function: KComplexCheckBox
        function KcomplexSelected(app, event)
            app.updateSamplingFrequencyEditability();
        end

        % Value changed function: DeltaWaveCheckBox
        function DeltaWaveSelected(app, event)
            app.updateSamplingFrequencyEditability();
        end

        % Value changed function: CAPCheckBox
        function CAPSelected(app, event)
            value = app.CAPCheckBox.Value;
            % Enable editig of Fs
            if value == 1
                app.EditField_2.Editable = "on";
            else
                app.EditField_2.Editable = "off";
            end
        end

        % Value changed function: FilenameEditField
        function FileNameChanged(app, event)
            value = app.FilenameEditField.Value;
            app.FileName = value;
        end

        % Button pushed function: GUIDEButton
        function GuideButtonPushed(app, event)

            % Create a new figure for the guide
            guideFig = uifigure('Name', 'RAVEN User Guide', ...
                                'Position', [100 100 600 700]);
            guideFig.Icon ='RAVEN_logo.png';
            
            % Add a multi-line, scrollable text area
            uitextarea(guideFig, ...
            'Position', [20 20 560 660], ...
            'Editable', 'off', ...
            'FontName', 'Gothic Century', ...
            'FontSize', 16, ...
            'Value', { ...
                'RAVEN User Guide', ...
                '', ...
                'Supported Signals', ...
                '-------------------', ...
                'RAVEN processes EEG data from original recordings.', ...
                'Supported file formats:', ...
                ' - EDF (European Data Format)', ...
                ' - SLF (SleepLab Format)', ...
                '', ...
                'Before Starting:', ...
                ' - When using "Group Analysis" mode for multiple subjects, ensure all EDF/SLF files are in the same folder for proper processing.', ...
                '', ...
                'How to Use RAVEN', ...
                '-------------------', ...
                '1. Select the microstructures for analysis. This allows modification of the default sampling frequency if needed.', ...
                '', ...
                '2. Adjust thresholds via the "Thresholds" button, which opens a separate menu.', ...
                '', ...
                '3. Choose the correct file type (EDF or SLF) from the dropdown menu according to your dataset.', ...
                '', ...
                '4. Choose the analysis mode:', ...
                '   - "Group Analysis": Batch processing of multiple subjects', ...
                '   - "Individual Focus": Single-subject analysis (requires input of Subject ID/Name)', ...
                '', ...
                '5. Specify the directory path containing the files you want to analyze.', ...
                '', ...
                '6. Press "Show Signals" to view all available signals across selected files.', ...
                '   NOTE: Signals not present in some files will be skipped during analysis.', ...
                '', ...
                '7. Add signals for microstructure analysis via the designated buttons. Reference subtraction can be applied if needed.', ...
                '', ...
                '8. Specify the save path for analysis results. Each subject will have a folder named with their ID and timestamp to prevent overwriting.', ...
                '', ...
                '9. Press "RUN RAVEN" to start the analysis.', ...
                '   - A progress dialog will display the analysis.', ...
                '   - You may cancel mid-analysis, but completed subjects will still be saved.', ...
                '', ...
                'Analysis Output', ...
                '-------------------', ...
                ' - Events are saved as .mat files in a folder named after the input file.', ...
                ' - Each event type is stored in a separate file.', ...
                ' - Timestamp is appended to avoid overwriting existing data.', ...
                ' - Feedback is provided at the end of the analysis, including skipped subjects or errors.' ...
            });


        end

        % Button pushed function: ResetSelectionButton
        function ResetSelectionButtonPushed(app, event)
            if ~isempty(app.SelectedSignalsListBox.Items)
                app.SelectedSignalsListBox.Items = {};
            end
            if ~isempty(app.CAPAnalysisSignalsListBox.Items)
                app.CAPAnalysisSignalsListBox.Items = {};
            end
        end

        % Value changed function: Switch
        function NormalisationSwitched(app, event)
            value = app.Switch.Value;

            if strcmp(value, 'On')
                app.TargetScaleLabel.Visible = "on";
                app.TargetScaleValue.Enable = "on";
                app.TargetScaleValue.Visible = "on";

                app.MethodLabel.Visible = "on";
                app.MethodDropDown.Visible ="on";
                app.MethodDropDown.Enable = "on";

                app.TargetScale = app.TargetScaleValue.Value;
                app.NormalisationEnabled = true;
                app.NormMethod = app.MethodDropDown.Value;
            elseif strcmp(value, 'Off')
                app.TargetScaleLabel.Visible = "off";
                app.TargetScaleValue.Enable = "off";
                app.TargetScaleValue.Visible = "off";

                app.MethodLabel.Visible = "off";
                app.MethodDropDown.Visible ="off";
                app.MethodDropDown.Enable = "off";

                app.TargetScale = NaN;
                app.NormMethod = NaN;
                app.NormalisationEnabled = false;
            end
            
        end

        % Value changed function: TargetScaleValue
        function TargetScaleChanged(app, event)
            value = app.TargetScaleValue.Value;

            app.TargetScale = value;
        
            if isfinite(value) && value > 0
                app.ConfirmationLabel.Visible = 'on';
        
                t = timer( ...
                    'StartDelay', 3, ...
                    'TimerFcn', @(~,~) hideLabel(app));
        
                start(t);
        
            end


        end

        % Value changed function: MethodDropDown
        function MethodChanged(app, event)
            value = app.MethodDropDown.Value;
            
            app.NormMethod = value;
        end

        % Image clicked function: Image2
        function InfoLogoClicked(app, event)
            % Create a new figure for the guide
            InfoFig = uifigure('Name', 'Signal Normalisation Info', ...
                                'Position', [200 250 600 420]);
            InfoFig.Icon ='RAVEN_logo.png';

            % Add a multi-line, scrollable text area
            uitextarea(InfoFig, ...
            'Position', [20 20 560 380], ...
            'Editable', 'off', ...
            'FontName', 'Gothic Century', ...
            'FontSize', 14, ...
            'Value', { ...
                'Signal normalisation is an optional preprocessing step that scales the selected signals to a user-defined amplitude. The purpose of this procedure is to reduce inter-subject variability in signal magnitude and improve the consistency of event detection across large datasets.',...
                '',...
                'Normalisation can be particularly useful when recordings contain attenuated, dampened, or otherwise low-amplitude signals in some subjects. By standardising signal scale, the fixed detection thresholds used by RAVEN become more robust across recordings.',...
                '',...
                'The selected normalisation method is used to estimate the current amplitude scale of each signal. The signal is then multiplied by a scaling factor defined as:',...
                '',...
                'Scaling Factor = Target Scale / Current Signal Scale',...
                '',...
                'Example: If the target interquartile range (IQR) is 25 µV and the current signal IQR is 20 µV, the scaling factor becomes:',...
                '',...
                '25 / 20 = 1.25. The signal is therefore multiplied by 1.25.',...
                '',...
                '',...
                'Available Normalisation Methods',...
                '------------------------------------',...
                'IQR: interquartial range',...
                'MAD: median absolute deviation',...
                'STD: standard deviation',...
                '',...
                'The default target scale was derived from the development dataset using the IQR method. The reference value was calculated from the C4 channel of three separate subjects as the median of the signal IQRs, selected based on signal quality and verified event content detected with default thresholds.',...
                '',...
                'When signal normalisation is enabled, RAVEN additionally outputs an Excel file containing, for each processed signal: • the signal name • the original calculated scale • the removed median value • and the applied scaling factor.',...
                '',...
                'If alternative normalisation methods are used, it is recommended to estimate an appropriate target scale with the favored normalisation method from a subset of high-quality recordings representative of the study population, as signal characteristics may vary substantially between datasets and acquisition systems.'
                
            });
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RAVEN_logo.png');
            app.DEFINESAMPLINGFREQUENCIESFORANALYSISPanel = uipanel(app.UIFigure);
            app.DEFINESAMPLINGFREQUENCIESFORANALYSISPanel.Title = 'DEFINE SAMPLING FREQUENCIES FOR ANALYSIS';
            app.GridLayout = uigridlayout(app.DEFINESAMPLINGFREQUENCIESFORANALYSISPanel);
            app.SamplingFrequencySpindleKcomplexDeltawavesLabel = uilabel(app.GridLayout);
            app.SamplingFrequencySpindleKcomplexDeltawavesLabel.Text = 'Sampling Frequency (Spindle, K-complex, Delta waves):';
            app.SamplingFrequencyCAPLabel = uilabel(app.GridLayout);
            app.SamplingFrequencyCAPLabel.Text = 'Sampling Frequency (CAP):';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @FsMicroChanged, true);
            app.EditField.Editable = 'off';
            app.EditField.Value = 256;
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @FsCapChanged, true);
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 128;
            app.DEFINEFILEFORMATPATHANDMODEPanel = uipanel(app.UIFigure);
            app.DEFINEFILEFORMATPATHANDMODEPanel.Title = 'DEFINE FILE FORMAT, PATH, AND MODE';
            app.GridLayout2 = uigridlayout(app.DEFINEFILEFORMATPATHANDMODEPanel);
            app.BrowseButton = uibutton(app.GridLayout2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChooseTheFileFormatDropDownLabel = uilabel(app.GridLayout2);
            app.ChooseTheFileFormatDropDownLabel.Text = 'Choose The File Format:';
            app.ChooseTheFileFormatDropDown = uidropdown(app.GridLayout2);
            app.ChooseTheFileFormatDropDown.Items = {'EDF', 'SLF'};
            app.ChooseTheFileFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatChanged, true);
            app.ChooseTheFileFormatDropDown.Value = 'EDF';
            app.DirectoryPathForFilesEditFieldLabel = uilabel(app.GridLayout2);
            app.DirectoryPathForFilesEditFieldLabel.Text = 'Directory Path For File(s):';
            app.DirectoryPathForFilesEditField = uieditfield(app.GridLayout2, 'text');
            app.SelectAnalysisModeLabel = uilabel(app.GridLayout2);
            app.SelectAnalysisModeLabel.Text = 'Select Analysis Mode:';
            app.SelectAnalysisModeDropDown = uidropdown(app.GridLayout2);
            app.SelectAnalysisModeDropDown.Items = {'Group Analysis', 'Individual Focus'};
            app.SelectAnalysisModeDropDown.ValueChangedFcn = createCallbackFcn(app, @IndividualFocus, true);
            app.SelectAnalysisModeDropDown.Value = 'Group Analysis';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout2);
            app.FilenameEditFieldLabel.Visible = 'off';
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.GridLayout2, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameChanged, true);
            app.FilenameEditField.Visible = 'off';
            app.DEFINECHANNELSOFINTERESTPanel = uipanel(app.UIFigure);
            app.DEFINECHANNELSOFINTERESTPanel.Title = 'DEFINE CHANNELS OF INTEREST';
            app.GridLayout3 = uigridlayout(app.DEFINECHANNELSOFINTERESTPanel);
            app.ShowSignalsButton = uibutton(app.GridLayout3, 'push');
            app.ShowSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSignalsButtonPushed, true);
            app.ShowSignalsButton.Text = 'Show Signals';
            app.AddCAPButton = uibutton(app.GridLayout3, 'push');
            app.AddCAPButton.ButtonPushedFcn = createCallbackFcn(app, @AddCAPButtonPushed, true);
            app.AddCAPButton.Text = 'Add (CAP) →';
            app.RemoveButton = uibutton(app.GridLayout3, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '← Remove';
            app.AddButton = uibutton(app.GridLayout3, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add →';
            app.AvailableSignalsListBoxLabel = uilabel(app.GridLayout3);
            app.AvailableSignalsListBoxLabel.Text = 'Available Signals';
            app.AvailableSignalsListBox = uilistbox(app.GridLayout3);
            app.AvailableSignalsListBox.Items = {};
            app.AvailableSignalsListBox.Multiselect = 'on';
            app.AvailableSignalsListBox.Value = {};
            app.ReferenceDropDownLabel = uilabel(app.GridLayout3);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.GridLayout3);
            app.ReferenceDropDown.Items = {'None'};
            app.ReferenceDropDown.Value = 'None';
            app.CAPAnalysisSignalsListBoxLabel = uilabel(app.GridLayout3);
            app.CAPAnalysisSignalsListBoxLabel.Text = 'CAP Analysis Signals';
            app.CAPAnalysisSignalsListBox = uilistbox(app.GridLayout3);
            app.CAPAnalysisSignalsListBox.Items = {};
            app.CAPAnalysisSignalsListBox.Multiselect = 'on';
            app.CAPAnalysisSignalsListBox.Value = {};
            app.SelectedSignalsListBoxLabel = uilabel(app.GridLayout3);
            app.SelectedSignalsListBoxLabel.Text = 'Selected Signals';
            app.SelectedSignalsListBox = uilistbox(app.GridLayout3);
            app.SelectedSignalsListBox.Items = {};
            app.SelectedSignalsListBox.Multiselect = 'on';
            app.SelectedSignalsListBox.Value = {};
            app.ResetSelectionButton = uibutton(app.GridLayout3, 'push');
            app.ResetSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSelectionButtonPushed, true);
            app.ResetSelectionButton.Text = {'Reset'; 'Selection'};
            app.SELECTMICROSTRUCTURESFORANALYSISPanel = uipanel(app.UIFigure);
            app.SELECTMICROSTRUCTURESFORANALYSISPanel.Title = 'SELECT MICROSTRUCTURES FOR ANALYSIS';
            app.GridLayout4 = uigridlayout(app.SELECTMICROSTRUCTURESFORANALYSISPanel);
            app.SpindleCheckBox = uicheckbox(app.GridLayout4);
            app.SpindleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpindleSelected, true);
            app.SpindleCheckBox.Text = 'Spindle';
            app.KComplexCheckBox = uicheckbox(app.GridLayout4);
            app.KComplexCheckBox.ValueChangedFcn = createCallbackFcn(app, @KcomplexSelected, true);
            app.KComplexCheckBox.Text = 'K-Complex';
            app.CAPCheckBox = uicheckbox(app.GridLayout4);
            app.CAPCheckBox.ValueChangedFcn = createCallbackFcn(app, @CAPSelected, true);
            app.CAPCheckBox.Text = 'CAP';
            app.DeltaWaveCheckBox = uicheckbox(app.GridLayout4);
            app.DeltaWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeltaWaveSelected, true);
            app.DeltaWaveCheckBox.Text = 'Delta Wave';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.GUIDEButton = uibutton(app.GridLayout5, 'push');
            app.GUIDEButton.ButtonPushedFcn = createCallbackFcn(app, @GuideButtonPushed, true);
            app.GUIDEButton.Text = 'GUIDE';
            app.THRESHOLDSButton = uibutton(app.GridLayout5, 'push');
            app.THRESHOLDSButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdsButtonPushed, true);
            app.THRESHOLDSButton.Text = 'THRESHOLDS';
            app.SELECTTHESAVEPATHPanel = uipanel(app.UIFigure);
            app.SELECTTHESAVEPATHPanel.Title = 'SELECT THE SAVE PATH';
            app.GridLayout6 = uigridlayout(app.SELECTTHESAVEPATHPanel);
            app.BrowseButton_2 = uibutton(app.GridLayout6, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SavePathEditFieldLabel = uilabel(app.GridLayout6);
            app.SavePathEditFieldLabel.Text = 'Save Path:';
            app.SavePathEditField = uieditfield(app.GridLayout6, 'text');
            app.RUNRAVENButton = uibutton(app.UIFigure, 'push');
            app.RUNRAVENButton.ButtonPushedFcn = createCallbackFcn(app, @RunRavenButonPushed, true);
            app.RUNRAVENButton.Text = {'RUN'; 'RAVEN'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Welcome to RAVEN'; '(Rhythmic Analysis of Variations in EEG Neural Activity)'; ''; 'RAVEN performs an advanced, automated analysis of overnight EEG recordings, with a focus on sleep microstructures. It detects and characterizes the following events:'; ''; 'Sleep Spindles'; ''; 'K-complexes'; ''; 'Delta Wave Sequences (Slow Wave Sleep, SWS)'; ''; 'Cyclic Alternating Pattern (CAP)'; ''; 'Key Features:'; ''; 'Works independently of prior sleep staging — no hypnogram required.'; ''; 'Can analyze data with a single EEG channel; CAP detection is optimized for two channels.'; ''; 'For detailed instructions on using RAVEN, click the Guide button to open the user guide.'};
            app.SIGNALNORMALISATIONPanel = uipanel(app.UIFigure);
            app.SIGNALNORMALISATIONPanel.Title = 'SIGNAL NORMALISATION';
            app.SignalNormalisation = uigridlayout(app.SIGNALNORMALISATIONPanel);
            app.Switch = uiswitch(app.SignalNormalisation, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @NormalisationSwitched, true);
            app.EnableDisableLabel = uilabel(app.SignalNormalisation);
            app.EnableDisableLabel.Text = 'Enable/Disable';
            app.TargetScaleLabel = uilabel(app.SignalNormalisation);
            app.TargetScaleLabel.Visible = 'off';
            app.TargetScaleLabel.Text = 'Target Scale';
            app.Label_2 = uilabel(app.SignalNormalisation);
            app.Label_2.Enable = 'off';
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.TargetScaleValue = uieditfield(app.SignalNormalisation, 'numeric');
            app.TargetScaleValue.Limits = [1 Inf];
            app.TargetScaleValue.ValueChangedFcn = createCallbackFcn(app, @TargetScaleChanged, true);
            app.TargetScaleValue.Enable = 'off';
            app.TargetScaleValue.Visible = 'off';
            app.TargetScaleValue.Value = 25.55;
            app.ConfirmationLabel = uilabel(app.SignalNormalisation);
            app.ConfirmationLabel.Visible = 'off';
            app.ConfirmationLabel.Text = 'Target Scale Changed ';
            app.MethodLabel = uilabel(app.SignalNormalisation);
            app.MethodLabel.Visible = 'off';
            app.MethodLabel.Text = 'Method';
            app.Label_3 = uilabel(app.SignalNormalisation);
            app.Label_3.Enable = 'off';
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.MethodDropDown = uidropdown(app.SignalNormalisation);
            app.MethodDropDown.Items = {'IQR', 'MAD', 'STD'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodChanged, true);
            app.MethodDropDown.Enable = 'off';
            app.MethodDropDown.Visible = 'off';
            app.MethodDropDown.Value = 'IQR';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @InfoLogoClicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'info_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RAVEN_desk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end