classdef ThresholdsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RestoreDefaultsButton           matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        CAPDETECTIONPanel               matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        ContinuousWaveletTransformEditField_3  matlab.ui.control.NumericEditField
        ContinuousWaveletTransformEditField_3Label  matlab.ui.control.Label
        ShortLongTermRatioEditField     matlab.ui.control.NumericEditField
        ShortLongTermRatioEditFieldLabel  matlab.ui.control.Label
        AmplitudeDifferenceEditField    matlab.ui.control.NumericEditField
        AmplitudeDifferenceEditFieldLabel  matlab.ui.control.Label
        EnvelopeFactorEditField_4       matlab.ui.control.NumericEditField
        EnvelopeFactorEditField_4Label  matlab.ui.control.Label
        SignalPowerEditField            matlab.ui.control.NumericEditField
        SignalPowerEditFieldLabel       matlab.ui.control.Label
        DELTAWAVEDETECTIONPanel         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        ContinuousWaveletTransformEditField_2  matlab.ui.control.NumericEditField
        ContinuousWaveletTransformEditField_2Label  matlab.ui.control.Label
        RootMeanSquareEditField_3       matlab.ui.control.NumericEditField
        RootMeanSquareEditField_3Label  matlab.ui.control.Label
        DeltaBandPowerEditField_2       matlab.ui.control.NumericEditField
        DeltaBandPowerEditField_2Label  matlab.ui.control.Label
        EnvelopeFactorEditField_3       matlab.ui.control.NumericEditField
        EnvelopeFactorEditField_3Label  matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthLabel                 matlab.ui.control.Label
        SPINDLEDETECTIONPanel           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ContinuousWaveletTransformEditField  matlab.ui.control.NumericEditField
        ContinuousWaveletTransformEditFieldLabel  matlab.ui.control.Label
        SigmaBandPowerEditField         matlab.ui.control.NumericEditField
        SigmaBandPowerEditFieldLabel    matlab.ui.control.Label
        RootMeanSquareEditField_2       matlab.ui.control.NumericEditField
        RootMeanSquareEditField_2Label  matlab.ui.control.Label
        EnvelopeFactorEditField_2       matlab.ui.control.NumericEditField
        EnvelopeFactorEditField_2Label  matlab.ui.control.Label
        RelativePowerEditField          matlab.ui.control.NumericEditField
        RelativePowerEditFieldLabel     matlab.ui.control.Label
        PeakToPeakEditField             matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        KCOMPLEXDETECTIONPanel          matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ContinuousWaveletTransformEditField_4  matlab.ui.control.NumericEditField
        ContinuousWaveletTransformEditField_4Label  matlab.ui.control.Label
        DeltaBandPowerEditField         matlab.ui.control.NumericEditField
        DeltaBandPowerLabel             matlab.ui.control.Label
        RootMeanSquareEditField         matlab.ui.control.NumericEditField
        RootMeanSquareLabel             matlab.ui.control.Label
        EnvelopeFactorEditField         matlab.ui.control.NumericEditField
        EnvelopeFactorEditFieldLabel    matlab.ui.control.Label
        CorrelationFactorEditField      matlab.ui.control.NumericEditField
        CorrelationFactorLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        Thresholds struct
        ParentApp RAVEN_desk
        DefaultThresholds struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, thresholds, defaultThresholds, parentApp)
            app.UIFigure.Icon = 'RAVEN_logo.png';
            app.UIFigure.Name = 'Adjustable Thresholds';
            app.Thresholds = thresholds;
            app.DefaultThresholds = defaultThresholds;
            app.ParentApp = parentApp;

            app.Label.Text = ['(' char(181) 'V)'];
            app.Label_2.Text = ['(' char(181) 'V' char(178) '/Hz)'];
            app.Label_3.Text = ['(' char(181) 'V)'];
            app.Label_5.Text = ['(' char(181) 'V)'];
            app.Label_6.Text = ['(' char(181) 'V)'];
            app.Label_7.Text = ['(' char(181) 'V' char(178) '/Hz)'];
            app.Label_8.Text = ['(' char(181) 'V)'];
            app.Label_9.Text = '(seconds)';
            app.Label_10.Text = ['(' char(181) 'V' char(178) '/Hz)'];
            app.Label_11.Text = ['(' char(181) 'V)'];
            app.Label_12.Text = ['(' char(181) 'V)'];
            app.Label_13.Text = ['(' char(181) 'V' char(178) '/Hz)'];
            app.Label_14.Text = ['(' char(181) 'V)'];
            app.Label_15.Text = ['(' char(181) 'V)'];
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            T = app.Thresholds;

            % Update the thresholds
            % K-complex
            T.Correlation_Kcomplex = app.CorrelationFactorEditField.Value;
            T.Envelope_Kcomplex = app.EnvelopeFactorEditField.Value;
            T.RMS_Kcomplex = app.RootMeanSquareEditField.Value;
            T.Power_Kcomplex = app.DeltaBandPowerEditField.Value;
            T.CWT_Kcomplex = app.ContinuousWaveletTransformEditField_4.Value;

            % Spindle
            T.P2P_spindle = app.PeakToPeakEditField.Value;
            T.RelativePower_spindle = app.RelativePowerEditField.Value;
            T.Envelope_spindle = app.EnvelopeFactorEditField_2.Value;
            T.RMS_spindle = app.RootMeanSquareEditField_2.Value;
            T.Power_spindle = app.SigmaBandPowerEditField.Value;
            T.CWT_spindle = app.ContinuousWaveletTransformEditField.Value;

            % Deltawaves
            T.WaveLength_SWS = app.WavelengthEditField.Value;
            T.Envelope_SWS = app.EnvelopeFactorEditField_3.Value;
            T.Power_SWS = app.DeltaBandPowerEditField_2.Value;
            T.RMS_SWS = app.RootMeanSquareEditField_3.Value;
            T.CWT_SWS = app.ContinuousWaveletTransformEditField_2.Value;

            % CAP
            T.Desc_CAP = app.ShortLongTermRatioEditField.Value;
            T.Envelope_CAP = app.EnvelopeFactorEditField_4.Value;
            T.Power_CAP = app.SignalPowerEditField.Value;
            T.AmpDiff_CAP = app.AmplitudeDifferenceEditField.Value;
            T.CWT_CAP = app.ContinuousWaveletTransformEditField_3.Value;

            app.ParentApp.Thresholds = T;
            delete(app.UIFigure);


        end

        % Button pushed function: RestoreDefaultsButton
        function SetToDefaultsButtonPushed(app, event)
            Thrs = app.DefaultThresholds;

            % K-complex
            app.ContinuousWaveletTransformEditField_4.Value = Thrs.CWT_Kcomplex;
            app.DeltaBandPowerEditField.Value = Thrs.Power_Kcomplex;
            app.RootMeanSquareEditField.Value = Thrs.RMS_Kcomplex;
            app.EnvelopeFactorEditField.Value = Thrs.Envelope_Kcomplex;
            app.CorrelationFactorEditField.Value = Thrs.Correlation_Kcomplex;

            % Spindle
            app.ContinuousWaveletTransformEditField.Value = Thrs.CWT_spindle;
            app.SigmaBandPowerEditField.Value = Thrs.Power_spindle;
            app.RootMeanSquareEditField_2.Value = Thrs.RMS_spindle;
            app.EnvelopeFactorEditField_2.Value = Thrs.Envelope_spindle;
            app.RelativePowerEditField.Value = Thrs.RelativePower_spindle;
            app.PeakToPeakEditField.Value = Thrs.P2P_spindle;

            % Delta waves
            app.ContinuousWaveletTransformEditField_2.Value = Thrs.CWT_SWS;
            app.RootMeanSquareEditField_3.Value = Thrs.RMS_SWS;
            app.DeltaBandPowerEditField_2.Value = Thrs.Power_SWS;
            app.EnvelopeFactorEditField_3.Value = Thrs.Envelope_SWS;
            app.WavelengthEditField.Value = Thrs.WaveLength_SWS;

            % CAP
            app.ContinuousWaveletTransformEditField_3.Value = Thrs.CWT_CAP;
            app.ShortLongTermRatioEditField.Value = Thrs.Desc_CAP;
            app.AmplitudeDifferenceEditField.Value = Thrs.AmpDiff_CAP;
            app.EnvelopeFactorEditField_4.Value = Thrs.Envelope_CAP;
            app.SignalPowerEditField.Value = Thrs.Power_CAP;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KCOMPLEXDETECTIONPanel = uipanel(app.UIFigure);
            app.KCOMPLEXDETECTIONPanel.Title = 'K-COMPLEX DETECTION';
            app.GridLayout = uigridlayout(app.KCOMPLEXDETECTIONPanel);
            app.CorrelationFactorLabel = uilabel(app.GridLayout);
            app.CorrelationFactorLabel.Text = 'Correlation Factor:';
            app.CorrelationFactorEditField = uieditfield(app.GridLayout, 'numeric');
            app.CorrelationFactorEditField.Value = 0.8;
            app.EnvelopeFactorEditFieldLabel = uilabel(app.GridLayout);
            app.EnvelopeFactorEditFieldLabel.Text = 'Envelope Factor:';
            app.EnvelopeFactorEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnvelopeFactorEditField.Value = 1.8;
            app.RootMeanSquareLabel = uilabel(app.GridLayout);
            app.RootMeanSquareLabel.Text = 'Root Mean Square:';
            app.RootMeanSquareEditField = uieditfield(app.GridLayout, 'numeric');
            app.RootMeanSquareEditField.Value = 17;
            app.DeltaBandPowerLabel = uilabel(app.GridLayout);
            app.DeltaBandPowerLabel.Text = 'Delta Band Power:';
            app.DeltaBandPowerEditField = uieditfield(app.GridLayout, 'numeric');
            app.DeltaBandPowerEditField.Value = 2.8;
            app.ContinuousWaveletTransformEditField_4Label = uilabel(app.GridLayout);
            app.ContinuousWaveletTransformEditField_4Label.Text = 'Continuous Wavelet Transform:';
            app.ContinuousWaveletTransformEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.ContinuousWaveletTransformEditField_4.Value = 16;
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.SPINDLEDETECTIONPanel = uipanel(app.UIFigure);
            app.SPINDLEDETECTIONPanel.Title = 'SPINDLE DETECTION';
            app.GridLayout2 = uigridlayout(app.SPINDLEDETECTIONPanel);
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = 'Peak To Peak:';
            app.PeakToPeakEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PeakToPeakEditField.Value = 26;
            app.RelativePowerEditFieldLabel = uilabel(app.GridLayout2);
            app.RelativePowerEditFieldLabel.Text = 'Relative Power:';
            app.RelativePowerEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RelativePowerEditField.Value = 0.9;
            app.EnvelopeFactorEditField_2Label = uilabel(app.GridLayout2);
            app.EnvelopeFactorEditField_2Label.Text = 'Envelope Factor:';
            app.EnvelopeFactorEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.EnvelopeFactorEditField_2.Value = 2;
            app.RootMeanSquareEditField_2Label = uilabel(app.GridLayout2);
            app.RootMeanSquareEditField_2Label.Text = 'Root Mean Square:';
            app.RootMeanSquareEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.RootMeanSquareEditField_2.Value = 8;
            app.SigmaBandPowerEditFieldLabel = uilabel(app.GridLayout2);
            app.SigmaBandPowerEditFieldLabel.Text = 'Sigma Band Power:';
            app.SigmaBandPowerEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SigmaBandPowerEditField.Value = 1.8;
            app.ContinuousWaveletTransformEditFieldLabel = uilabel(app.GridLayout2);
            app.ContinuousWaveletTransformEditFieldLabel.Text = 'Continuous Wavelet Transform:';
            app.ContinuousWaveletTransformEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ContinuousWaveletTransformEditField.Value = 12;
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '';
            app.DELTAWAVEDETECTIONPanel = uipanel(app.UIFigure);
            app.DELTAWAVEDETECTIONPanel.Title = 'DELTA WAVE DETECTION';
            app.GridLayout3 = uigridlayout(app.DELTAWAVEDETECTIONPanel);
            app.WavelengthLabel = uilabel(app.GridLayout3);
            app.WavelengthLabel.Text = 'Wavelength:';
            app.WavelengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.WavelengthEditField.Value = 0.35;
            app.EnvelopeFactorEditField_3Label = uilabel(app.GridLayout3);
            app.EnvelopeFactorEditField_3Label.Text = 'Envelope Factor:';
            app.EnvelopeFactorEditField_3 = uieditfield(app.GridLayout3, 'numeric');
            app.EnvelopeFactorEditField_3.Value = 2.5;
            app.DeltaBandPowerEditField_2Label = uilabel(app.GridLayout3);
            app.DeltaBandPowerEditField_2Label.Text = 'Delta Band Power:';
            app.DeltaBandPowerEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.DeltaBandPowerEditField_2.Value = 2.4;
            app.RootMeanSquareEditField_3Label = uilabel(app.GridLayout3);
            app.RootMeanSquareEditField_3Label.Text = 'Root Mean Square:';
            app.RootMeanSquareEditField_3 = uieditfield(app.GridLayout3, 'numeric');
            app.RootMeanSquareEditField_3.Value = 33;
            app.ContinuousWaveletTransformEditField_2Label = uilabel(app.GridLayout3);
            app.ContinuousWaveletTransformEditField_2Label.Text = 'Continuous Wavelet Transform:';
            app.ContinuousWaveletTransformEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.ContinuousWaveletTransformEditField_2.Value = 15;
            app.Label_9 = uilabel(app.GridLayout3);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.GridLayout3);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.GridLayout3);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.GridLayout3);
            app.Label_12.Text = '';
            app.CAPDETECTIONPanel = uipanel(app.UIFigure);
            app.CAPDETECTIONPanel.Title = 'CAP DETECTION';
            app.GridLayout4 = uigridlayout(app.CAPDETECTIONPanel);
            app.SignalPowerEditFieldLabel = uilabel(app.GridLayout4);
            app.SignalPowerEditFieldLabel.Text = 'Signal Power:';
            app.SignalPowerEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SignalPowerEditField.Value = 2;
            app.EnvelopeFactorEditField_4Label = uilabel(app.GridLayout4);
            app.EnvelopeFactorEditField_4Label.Text = 'Envelope Factor:';
            app.EnvelopeFactorEditField_4 = uieditfield(app.GridLayout4, 'numeric');
            app.EnvelopeFactorEditField_4.Value = 3;
            app.AmplitudeDifferenceEditFieldLabel = uilabel(app.GridLayout4);
            app.AmplitudeDifferenceEditFieldLabel.Text = 'Amplitude Difference:';
            app.AmplitudeDifferenceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AmplitudeDifferenceEditField.Value = 50;
            app.ShortLongTermRatioEditFieldLabel = uilabel(app.GridLayout4);
            app.ShortLongTermRatioEditFieldLabel.Text = 'Short-Long Term Ratio:';
            app.ShortLongTermRatioEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ShortLongTermRatioEditField.Value = 0.16;
            app.ContinuousWaveletTransformEditField_3Label = uilabel(app.GridLayout4);
            app.ContinuousWaveletTransformEditField_3Label.Text = 'Continuous Wavelet Transform:';
            app.ContinuousWaveletTransformEditField_3 = uieditfield(app.GridLayout4, 'numeric');
            app.ContinuousWaveletTransformEditField_3.Value = 10;
            app.Label_13 = uilabel(app.GridLayout4);
            app.Label_13.Text = '';
            app.Label_14 = uilabel(app.GridLayout4);
            app.Label_14.Text = '';
            app.Label_15 = uilabel(app.GridLayout4);
            app.Label_15.Text = '';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RestoreDefaultsButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @SetToDefaultsButtonPushed, true);
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end